classdef UserInterfacePanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        TabGroup3            matlab.ui.container.TabGroup
        Tab_3                matlab.ui.container.Tab
        UIAxes11             matlab.ui.control.UIAxes
        Tab2_2               matlab.ui.container.Tab
        UIAxes12             matlab.ui.control.UIAxes
        TabGroup2            matlab.ui.container.TabGroup
        Tab_2                matlab.ui.container.Tab
        GridLayout3          matlab.ui.container.GridLayout
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        PosError             matlab.ui.control.UIAxes
        Tab2                 matlab.ui.container.Tab
        OrientationError     matlab.ui.control.UIAxes
        OperationModePanel   matlab.ui.container.Panel
        Lamp                 matlab.ui.control.Lamp
        EmergencyStopButton  matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
        StatusLamp           matlab.ui.control.Lamp
        Path3Button          matlab.ui.control.Button
        Path2Button          matlab.ui.control.Button
        Path1Button          matlab.ui.control.Button
        PositionAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot      % Peter Corke SerialLink robot model;


       Path1Data = cell(1,7);
       Path2Data = cell(1,7);
       Path3Data = cell(1,7);

    
        d1 = 5;
        d2 = 5;
        d3 = 25;
        d4 = 5;
        d5 = 25;
    end
    
    methods (Access = private)


    function animateRobot(app, q_history) 
    axes(app.VisualizerAxes);  % Select GUI axes

    % Plot options used once
    app.robot.plotopt = {'delay', 0.01, 'noname', 'noshadow', ...
                         'nojaxes'};
    
    % 1) Plot the initial pose once
    app.robot.plot(q_history(:,1)');
    
    % 2) Animate the remaining frames
    for k = 2:size(q_history, 2)
        app.robot.animate(q_history(:,k)');
        drawnow limitrate;
    end
end


    end % end of all private helper methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Build DH model
        L(1) = Link([0      0        0        0]);
        L(2) = Link([0      app.d1  -app.d2   pi/2]);
        L(3) = Link([0      0        app.d3    0]);
        L(4) = Link([0     -app.d4   0        pi/2]);
        
        % define robot
        app.robot = SerialLink(L, 'name', 'FlatRide');
        
        % 5th DH row to get to ee position
        app.robot.tool = trotz(0) * transl(0, 0, app.d5);


        end

        % Button pushed function: Path1Button
        function Path1ButtonPushed(app, event)
          [app.Path1Data{:}] = control_loop(1);
          perr_log = app.Path1Data{3};
          werr_log = app.Path1Data{4};  
          q_log = app.Path1Data{5};
          t = app.Path1Data{6};
          animateRobot(app, q_log);

          % --- Plot Position Error vs Time ---
            plot(app.PosError, t, perr_log, 'LineWidth', 1.5);
            xlabel(app.PosError, 'Time (s)');
            ylabel(app.PosError, 'Position Error (cm)');
            title(app.PosError, 'Position Error vs. Time');
            grid(app.PosError, 'on');

          % --- Plot Orientation Error vs Time ---
            plot(app.OrientationError, t, werr_log, 'LineWidth', 1.5);
            xlabel(app.OrientationError, 'Time (s)');
            ylabel(app.OrientationError, 'Orientation Error (rad)');
            title(app.OrientationError, 'Orientation Error vs. Time');
            grid(app.OrientationError, 'on');
          

        end

        % Button pushed function: Path2Button
        function Path2ButtonPushed(app, event)
          q_history = app.Path2Data; % take the matrix that represents the q vals
          animateRobot(app, q_history);
        end

        % Button pushed function: Path3Button
        function Path3ButtonPushed(app, event)
          q_history = app.Path3Data; % take the matrix that represents the q vals
          animateRobot(app, q_history);  
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PositionAxes = uiaxes(app.GridLayout);
            title(app.PositionAxes, 'Position Error Magnitude')
            xlabel(app.PositionAxes, 'X')
            ylabel(app.PositionAxes, 'Y')
            zlabel(app.PositionAxes, 'Z')
            app.OperationModePanel = uipanel(app.GridLayout);
            app.OperationModePanel.Title = 'Operation Mode';
            app.Path1Button = uibutton(app.OperationModePanel, 'push');
            app.Path1Button.ButtonPushedFcn = createCallbackFcn(app, @Path1ButtonPushed, true);
            app.Path1Button.Text = 'Path 1';
            app.Path2Button = uibutton(app.OperationModePanel, 'push');
            app.Path2Button.ButtonPushedFcn = createCallbackFcn(app, @Path2ButtonPushed, true);
            app.Path2Button.Text = 'Path 2';
            app.Path3Button = uibutton(app.OperationModePanel, 'push');
            app.Path3Button.ButtonPushedFcn = createCallbackFcn(app, @Path3ButtonPushed, true);
            app.Path3Button.Text = 'Path 3';
            app.StatusLamp = uilamp(app.OperationModePanel);
            app.StartButton = uibutton(app.OperationModePanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EmergencyStopButton = uibutton(app.OperationModePanel, 'push');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.Lamp = uilamp(app.OperationModePanel);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.PosError = uiaxes(app.Tab);
            title(app.PosError, 'Positional Error')
            xlabel(app.PosError, 't')
            ylabel(app.PosError, 'Position')
            zlabel(app.PosError, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.OrientationError = uiaxes(app.Tab2);
            title(app.OrientationError, 'Orientation Error')
            xlabel(app.OrientationError, 't')
            ylabel(app.OrientationError, 'theta')
            zlabel(app.OrientationError, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.GridLayout3 = uigridlayout(app.Tab_2);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Joint 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Joint 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Joint 3')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Joint 4')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.TabGroup3 = uitabgroup(app.GridLayout);
            app.Tab_3 = uitab(app.TabGroup3);
            app.Tab_3.Title = 'Tab';
            app.UIAxes11 = uiaxes(app.Tab_3);
            title(app.UIAxes11, 'End Effector Position')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.Tab2_2 = uitab(app.TabGroup3);
            app.Tab2_2.Title = 'Tab2';
            app.UIAxes12 = uiaxes(app.Tab2_2);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterfacePanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end